classdef Snake_MQTT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        ScoreLabel  matlab.ui.control.Label
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        GridSize = 20
        Snake                 % Nx2 [x y], head in row 1
        Dir = [1 0]
        NextDir = [1 0]
        Food
        Score = 0
        GameTimer
        SnakePlot
        FoodPlot
        MqttClient
        BrokerURL  = "tcp://broker.hivemq.com"
        CmdTopic   = "me435/fulksdm/#"         
        StateTopic = "me435/fulksdm/to_pi"
    end
    
    methods (Access = private)
        
        function connectMqtt(app)
            try
                app.MqttClient = mqttclient(app.BrokerURL);
                subscribe(app.MqttClient, app.CmdTopic, ...
                    'Callback', @(topic, data) app.onMqttMessage(topic, data));
            catch ME
                     uialert(app.UIFigure, ME.message, 'MQTT connection failed');
                end
            end


        function onMqttMessage(app, topic, data)
            try
                if string(topic) == app.StateTopic, return, end
        
                msg = jsondecode(char(data));
                if ~isfield(msg, 'type') || ~strcmp(msg.type, 'direction')
                    return
                end
                cmd = lower(strtrim(string(msg.payload)));
        
                switch cmd
                    case "north", d = [0 1];
                    case "south", d = [0 -1];
                    case "east",  d = [1 0];
                    case "west",  d = [-1 0];
                    otherwise
                        fprintf('UNMATCHED cmd=[%s]\n', cmd);
                        return
                end
        
                running = ~isempty(app.GameTimer) && isvalid(app.GameTimer) ...
                          && strcmp(app.GameTimer.Running, 'on');
                if ~running
                    app.startGame();
                end
                app.setDirection(d);
                fprintf('cmd=%s d=[%d %d] NextDir=[%d %d]\n', cmd, d, app.NextDir);
            catch ME
                fprintf(2, '%s\n', ME.getReport());
            end
        end
        

        function setDirection(app, d)
            % Compare against the direction actually applied last tick,
            % not NextDir, so fast double-taps can't reverse the snake
            if ~isequal(d, -app.Dir)
                app.NextDir = d;
            end
        end


        function startGame(app)
            app.stopTimer();
            mid = round(app.GridSize/2);
            app.Snake = [mid mid; mid-1 mid; mid-2 mid];
            app.Dir = [1 0];  app.NextDir = [1 0];
            app.Score = 0;
            app.publishScore(); 
            app.ScoreLabel.Text = "Score: 0";
            app.spawnFood();
            app.render();
            app.GameTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.12, ...
                'BusyMode', 'drop', 'TimerFcn', @(~,~) app.stepGame());
            start(app.GameTimer);
        end

        function publishScore(app)
            if isempty(app.MqttClient), return, end
            msg = struct('type', 'score', 'payload', min(app.Score, 15));
            write(app.MqttClient, app.StateTopic, jsonencode(msg));
        end
        

        function stopTimer(app)
            if ~isempty(app.GameTimer) && isvalid(app.GameTimer)
                stop(app.GameTimer);
                delete(app.GameTimer);
            end
        end
        
        function endGame(app)
            app.stopTimer();
            app.publishState("gameover");
        end


        function stepGame(app)
            app.Dir = app.NextDir;
            newHead = app.Snake(1,:) + app.Dir;
        
            hitWall = any(newHead < 1 | newHead > app.GridSize);
            hitSelf = ismember(newHead, app.Snake(1:end-1,:), 'rows');
            if hitWall || hitSelf
                app.endGame();
                return
            end

            app.Snake = [newHead; app.Snake];
            if isequal(newHead, app.Food)
                app.Score = app.Score + 1;
                app.ScoreLabel.Text = "Score: " + app.Score;
                app.spawnFood();
                app.publishScore();
                
            else
                app.Snake(end,:) = [];
            end
            app.render();
        end

        function spawnFood(app)
            [X, Y] = meshgrid(1:app.GridSize);
            free = setdiff([X(:) Y(:)], app.Snake, 'rows');
            if isempty(free)
                app.endGame();   % board full, you win
                return
            end
            app.Food = free(randi(size(free,1)), :);
        end
        

        function render(app)
            set(app.SnakePlot, 'XData', app.Snake(:,1), 'YData', app.Snake(:,2));
            set(app.FoodPlot,  'XData', app.Food(1),    'YData', app.Food(2));
            drawnow limitrate
        end

        function UIFigureCloseRequest(app, event)
            app.stopTimer();
            app.MqttClient = [];   % releasing the client disconnects it
            delete(app);
        end

        function publishState(app, status)
            if isempty(app.MqttClient), return, end
            s = struct('status', status, 'score', app.Score, ...
                       'head', app.Snake(1,:), 'food', app.Food);
            write(app.MqttClient, app.StateTopic, jsonencode(s));
        end


        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                ax = app.UIAxes;
                axis(ax, 'off');
                ax.XLim = [0 app.GridSize+1];
                ax.YLim = [0 app.GridSize+1];
                ax.PlotBoxAspectRatio = [1 1 1];
                hold(ax, 'on');
                app.SnakePlot = plot(ax, nan, nan, 's', 'MarkerSize', 14, ...
                    'MarkerFaceColor', [0.2 0.7 0.3], 'MarkerEdgeColor', 'none');
                app.FoodPlot = plot(ax, nan, nan, 'o', 'MarkerSize', 12, ...
                    'MarkerFaceColor', [0.9 0.2 0.2], 'MarkerEdgeColor', 'none');
                app.connectMqtt();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Snake_MQTT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end