classdef Lab6GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab6GUIFigure             matlab.ui.Figure
        SensorStreamingPanel      matlab.ui.container.Panel
        TimeTimestampLabel        matlab.ui.control.Label
        RightLamp                 matlab.ui.control.Lamp
        RightLampLabel            matlab.ui.control.Label
        MiddleLamp                matlab.ui.control.Lamp
        MiddleLampLabel           matlab.ui.control.Label
        LeftLamp                  matlab.ui.control.Lamp
        LeftLampLabel             matlab.ui.control.Label
        StreamingSwitch           matlab.ui.control.Switch
        StreamingSwitchLabel      matlab.ui.control.Label
        ModesPanel                matlab.ui.container.Panel
        StopLineFollowingButton   matlab.ui.control.Button
        StartLineFollowingButton  matlab.ui.control.Button
        DriveUntilLineButton      matlab.ui.control.Button
        DriveUntilWallButton      matlab.ui.control.Button
        BeepButton                matlab.ui.control.Button
        ClickintheareabelowtousethekeyboardLabel  matlab.ui.control.Label
        SpeedSlider               matlab.ui.control.Slider
        SpeedSliderLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        mqClient
        priorKey = ""

    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if strcmp(messageType, "line_sensor_reading")
                
                messagePayload = messageStruct.payload;
                app.TimeTimestampLabel.Text = messagePayload.timestamp
                app.LeftLamp.Color = messagePayload.left
                app.MiddleLamp.Color = messagePayload.middle;
                app.RightLamp.Color = messagePayload.right;

            end

        end

        function sendMessage(app, messageType, messagePayload)

            messageStruct.type = messageType;

            if nargin > 2
                messageStruct.payload = messagePayload;
            end

            messageJson = jsonencode(messageStruct);

            app.mqClient.write("me435/fulksdm/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");

            app.mqClient.subscribe("me435/fulksdm/#", "Callback", @app.myCallback);

        end

        % Key press function: Lab6GUIFigure
        function Lab6GUIFigureKeyPress(app, event)
            
            key = event.Key
            if strcmp(key, app.priorKey)
                return;
            end
            app.priorKey = key;

            speed = round(app.SpeedSlider.Value);

            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed, speed, speed, speed])
            end
            
            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed, -speed, -speed, -speed])
            end

            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed, speed, speed, -speed])
            end

            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds", [speed, -speed, -speed, speed])
            end

            if strcmp(key, "pageup")
                app.sendMessage("set_speeds", [-speed, -speed, speed, speed])
            end

            if strcmp(key, "pagedown")
                app.sendMessage("set_speeds", [speed, speed, -speed, -speed])
            end
        end

        % Key release function: Lab6GUIFigure
        function Lab6GUIFigureKeyRelease(app, event)
            
            app.priorKey = "";
            app.sendMessage("stop")
            
        end

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            
            app.sendMessage("beep")

        end

        % Button pushed function: DriveUntilWallButton
        function DriveUntilWallButtonPushed(app, event)
            
            app.sendMessage("mode", "drive_until_wall")

        end

        % Button pushed function: DriveUntilLineButton
        function DriveUntilLineButtonPushed(app, event)
            
            app.sendMessage("mode", "drive_until_line")

        end

        % Button pushed function: StartLineFollowingButton
        function StartLineFollowingButtonPushed(app, event)
            
            app.sendMessage("mode", "line_following")

        end

        % Button pushed function: StopLineFollowingButton
        function StopLineFollowingButtonPushed(app, event)
            
            app.sendMessage("mode", "none")
            app.sendMessage("mode", "none")

        end

        % Value changed function: StreamingSwitch
        function StreamingSwitchValueChanged(app, event)
            
            value = app.StreamingSwitch.Value;
            
            if strcmp(value, "On")
                app.sendMessage("is_streaming", true);
            else
                app.sendMessage("is_streaming", false);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab6GUIFigure = uifigure('Visible', 'off');
            app.Lab6GUIFigure.Name = 'Lab6GUI';
            app.Lab6GUIFigure.KeyPressFcn = createCallbackFcn(app, @Lab6GUIFigureKeyPress, true);
            app.Lab6GUIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab6GUIFigureKeyRelease, true);
            app.SpeedSliderLabel = uilabel(app.Lab6GUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.Lab6GUIFigure);
            app.SpeedSlider.Value = 40;
            app.ClickintheareabelowtousethekeyboardLabel = uilabel(app.Lab6GUIFigure);
            app.ClickintheareabelowtousethekeyboardLabel.Text = 'Click in the area below to use the keyboard';
            app.BeepButton = uibutton(app.Lab6GUIFigure, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            app.ModesPanel = uipanel(app.Lab6GUIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveUntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilWallButtonPushed, true);
            app.DriveUntilWallButton.Text = 'Drive Until Wall';
            app.DriveUntilLineButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilLineButtonPushed, true);
            app.DriveUntilLineButton.Text = 'Drive Until Line';
            app.StartLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLineFollowingButtonPushed, true);
            app.StartLineFollowingButton.Text = 'Start Line Following';
            app.StopLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StopLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StopLineFollowingButtonPushed, true);
            app.StopLineFollowingButton.Text = 'Stop Line Following';
            app.SensorStreamingPanel = uipanel(app.Lab6GUIFigure);
            app.SensorStreamingPanel.Title = 'Sensor Streaming';
            app.StreamingSwitchLabel = uilabel(app.SensorStreamingPanel);
            app.StreamingSwitchLabel.Text = 'Streaming';
            app.StreamingSwitch = uiswitch(app.SensorStreamingPanel, 'slider');
            app.StreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.SensorStreamingPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.SensorStreamingPanel);
            app.MiddleLampLabel = uilabel(app.SensorStreamingPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.SensorStreamingPanel);
            app.RightLampLabel = uilabel(app.SensorStreamingPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.SensorStreamingPanel);
            app.TimeTimestampLabel = uilabel(app.SensorStreamingPanel);
            app.TimeTimestampLabel.Text = 'Time: Timestamp';
            % Show the figure after all components are created
            app.Lab6GUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab6GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab6GUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab6GUIFigure)
        end
    end
end