classdef ABBArmGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure     matlab.ui.Figure
        ValuePanel_2             matlab.ui.container.Panel
        SAVEButton_3             matlab.ui.control.Button
        GOButton_4               matlab.ui.control.Button
        Label_3                  matlab.ui.control.Label
        SAVEButton_2             matlab.ui.control.Button
        GOButton_3               matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        SAVEButton               matlab.ui.control.Button
        GOButton_2               matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        ValuePanel               matlab.ui.container.Panel
        J6ValueEditField         matlab.ui.control.NumericEditField
        J6ValueEditFieldLabel    matlab.ui.control.Label
        J4ValueEditField         matlab.ui.control.NumericEditField
        J4ValueEditFieldLabel    matlab.ui.control.Label
        J5ValueEditField         matlab.ui.control.NumericEditField
        J5ValueEditFieldLabel    matlab.ui.control.Label
        HOMEButton               matlab.ui.control.Button
        GOButton                 matlab.ui.control.Button
        J3ValueEditField         matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel    matlab.ui.control.Label
        J2ValueEditField         matlab.ui.control.NumericEditField
        J2ValueEditField_2Label  matlab.ui.control.Label
        J1ValueEditField         matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel    matlab.ui.control.Label
        SliderPanel              matlab.ui.container.Panel
        Theta1Label              matlab.ui.control.Label
        Theta6Slider             matlab.ui.control.Slider
        Theta6SliderLabel        matlab.ui.control.Label
        Theta4Slider             matlab.ui.control.Slider
        Theta4SliderLabel        matlab.ui.control.Label
        Theta5Slider             matlab.ui.control.Slider
        Theta5SliderLabel        matlab.ui.control.Label
        Theta3Slider             matlab.ui.control.Slider
        Theta3SliderLabel        matlab.ui.control.Label
        Theta2Slider             matlab.ui.control.Slider
        Theta2SliderLabel        matlab.ui.control.Label
        Joint1Slider             matlab.ui.control.Slider
        axes_arm                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        link5Patch
        link5Vertices
        link6Patch
        link6Vertices

        jointAngles = [0 -90 0 0 0 0];

        savedAngles1 = [0 0 0 0 0 0];
        savedAngles2 = [0 0 0 0 0 0];
        savedAngles3 = [0 0 0 0 0 0];
        savedAngles4 = [0 0 0 0 0 0];
        savedAngles5 = [0 0 0 0 0 0];
        savedAngles6 = [0 0 0 0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)

            % Create the A homogeneous transformation matrices for the given jointAngles.
        A1 = create_A_matrix(0, 290, -90, app.jointAngles(1));
        A2 = create_A_matrix(270, 0, 0, app.jointAngles(2));
        A3 = create_A_matrix(70, 0, -90, app.jointAngles(3));
        A4 = create_A_matrix(0, 302, 90, app.jointAngles(4));
        A5 = create_A_matrix(0, 0, -90, app.jointAngles(5));
        A6 = create_A_matrix(0, 72, 0, app.jointAngles(6));
        
        % Use the current A matricies to form the T0_n matricies.
        T0_1 = A1;
        T0_2 = A1 * A2;
        T0_3 = A1 * A2 * A3;
        T0_4 = A1 * A2 * A3 * A4;
        T0_5 = A1 * A2 * A3 * A4 * A5;
        T0_6 = A1 * A2 * A3 * A4 * A5 * A6;
        
        % Use the current T matricies to transform the original patch vertices
        link1VerticesWRTground = T0_1 * app.link1Vertices;
        link2VerticesWRTground = T0_2 * app.link2Vertices;
        link3VerticesWRTground = T0_3 * app.link3Vertices;
        link4VerticesWRTground = T0_4 * app.link4Vertices;
        link5VerticesWRTground = T0_5 * app.link5Vertices;
        link6VerticesWRTground = T0_6 * app.link6Vertices;
        
        % Update the patches with the newly transformed vertices
        set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
        set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
        set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
        set(app.link5Patch,'Vertices', link5VerticesWRTground(1:3,:)');
        set(app.link6Patch,'Vertices', link6VerticesWRTground(1:3,:)');

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

    
    % Prepare the axes_arm properties
    app.axes_arm.View = [90 -50];
    app.axes_arm.Visible = 'off';
    
    % Once you are in Matlab App Designer you can try these axes_arm properties too.
    % These properties keep the Axes from automatically resizing (optional)
    app.axes_arm.XLim = [-200 1000];
    app.axes_arm.YLim = [-200 200];
    app.axes_arm.ZLim = [0 1000];
    
    % Create vertices for all the patches
    makeStlLink("ABBL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
    % Save references to the vertices of each patch, make points 4x1 not 3x1.
    app.link1Patch = makeStlLink("ABBL1.stl", app.axes_arm, [112/255 198/255 210/255]);
    app.link1Vertices = get(app.link1Patch, 'Vertices')';
    app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
    
    app.link2Patch = makeStlLink("ABBL2.stl", app.axes_arm, [198/255 172/255 20/255]);
    app.link2Vertices = get(app.link2Patch, 'Vertices')';
    app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

    app.link3Patch = makeStlLink("ABBL3.stl", app.axes_arm, [181/255 24/255 35/255]);
    app.link3Vertices = get(app.link3Patch, 'Vertices')';
    app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

    app.link4Patch = makeStlLink("ABBL4.stl", app.axes_arm, [212/255 115/255 90/255]);
    app.link4Vertices = get(app.link4Patch, 'Vertices')';
    app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

    app.link5Patch = makeStlLink("ABBL5.stl", app.axes_arm, [100/255 200/255 12/255]);
    app.link5Vertices = get(app.link5Patch, 'Vertices')';
    app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));

    app.link6Patch = makeStlLink("ABBL6.stl", app.axes_arm, [200/255 50/255 120/255]);
    app.link6Vertices = get(app.link6Patch, 'Vertices')';
    app.link6Vertices(4,:) = ones(1, size(app.link6Vertices,2));
    % End: Code that can go into your GUI's opening function.
    
    updateArm(app);


        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.J1ValueEditField.Value = changingValue;
            app.updateArm()
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(2) = changingValue - 90;
            app.J2ValueEditField.Value = changingValue;
            app.updateArm()
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(3) = changingValue;
            app.J3ValueEditField.Value = changingValue;
            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.jointAngles = [app.J1ValueEditField.Value, app.J2ValueEditField.Value - 90, app.J3ValueEditField.Value, app.J4ValueEditField.Value, app.J5ValueEditField.Value, app.J6ValueEditField.Value];
            app.Joint1Slider.Value = app.J1ValueEditField.Value;
            app.Theta2Slider.Value = app.J2ValueEditField.Value;
            app.Theta3Slider.Value = app.J3ValueEditField.Value;
            app.Theta4Slider.Value = app.J4ValueEditField.Value;
            app.Theta5Slider.Value = app.J5ValueEditField.Value;
            app.Theta6Slider.Value = app.J6ValueEditField.Value;

   
            app.updateArm()
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles = [0, -90, 0, 0, 0, 0];
            app.Joint1Slider.Value = 0;
            app.Theta2Slider.Value = 0;
            app.Theta3Slider.Value = 0;
            app.Theta4Slider.Value = 0;
            app.Theta5Slider.Value = 0;
            app.Theta6Slider.Value = 0;

            app.J1ValueEditField.Value = 0;
            app.J2ValueEditField.Value = 0;
            app.J3ValueEditField.Value = 0;
            app.J4ValueEditField.Value = 0;
            app.J5ValueEditField.Value = 0;
            app.J6ValueEditField.Value = 0;

            app.updateArm()
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.savedAngles1 = app.jointAngles;
            app.Label.Text = string(app.savedAngles1(1) + ", " + (app.savedAngles1(2) + 90) + ", " + app.savedAngles1(3) + ", " + app.savedAngles1(4) + ", " + app.savedAngles1(5) + ", " + app.savedAngles1(6));
        end

        % Button pushed function: GOButton_2
        function GOButton_2Pushed(app, event)
            app.jointAngles = app.savedAngles1;

            app.Joint1Slider.Value = app.savedAngles1(1);
            app.Theta2Slider.Value = app.savedAngles1(2) +90;
            app.Theta3Slider.Value = app.savedAngles1(3);
            app.Theta4Slider.Value = app.savedAngles1(4);
            app.Theta5Slider.Value = app.savedAngles1(5);
            app.Theta6Slider.Value = app.savedAngles1(6);

            app.J1ValueEditField.Value = app.savedAngles1(1);
            app.J2ValueEditField.Value = app.savedAngles1(2)+90;
            app.J3ValueEditField.Value = app.savedAngles1(3);
            app.J4ValueEditField.Value = app.savedAngles1(4);
            app.J5ValueEditField.Value = app.savedAngles1(5);
            app.J6ValueEditField.Value = app.savedAngles1(6);
            app.updateArm()
        end

        % Button pushed function: SAVEButton_2
        function SAVEButton_2Pushed(app, event)
            app.savedAngles2 = app.jointAngles;
            app.Label_2.Text = string(app.savedAngles2(1) + ", " + (app.savedAngles2(2) + 90) + ", " + app.savedAngles2(3)+ ", " + app.savedAngles2(4)+ ", " + app.savedAngles2(5)+ ", " + app.savedAngles2(6));
        end

        % Button pushed function: GOButton_3
        function GOButton_3Pushed(app, event)
            app.jointAngles = app.savedAngles2;

            app.Joint1Slider.Value = app.savedAngles2(1);
            app.Theta2Slider.Value = app.savedAngles2(2) +90;
            app.Theta3Slider.Value = app.savedAngles2(3);
            app.Theta4Slider.Value = app.savedAngles2(4);
            app.Theta5Slider.Value = app.savedAngles2(5);
            app.Theta6Slider.Value = app.savedAngles2(6);


            app.J1ValueEditField.Value = app.savedAngles2(1);
            app.J2ValueEditField.Value = app.savedAngles2(2)+90;
            app.J3ValueEditField.Value = app.savedAngles2(3);
            app.J4ValueEditField.Value = app.savedAngles2(4);
            app.J5ValueEditField.Value = app.savedAngles2(5);
            app.J6ValueEditField.Value = app.savedAngles2(6);
            app.updateArm()
        end

        % Button pushed function: SAVEButton_3
        function SAVEButton_3Pushed(app, event)
            app.savedAngles3 = app.jointAngles;
            app.Label_3.Text = string(app.savedAngles3(1) + ", " + (app.savedAngles3(2) + 90) + ", " + app.savedAngles3(3) + ", " + app.savedAngles3(4) + ", " + app.savedAngles3(5) + ", " + app.savedAngles3(6));
        end

        % Button pushed function: GOButton_4
        function GOButton_4Pushed(app, event)
            app.jointAngles = app.savedAngles3;

            app.Joint1Slider.Value = app.savedAngles3(1);
            app.Theta2Slider.Value = app.savedAngles3(2) +90;
            app.Theta3Slider.Value = app.savedAngles3(3);
            app.Theta4Slider.Value = app.savedAngles3(4);
            app.Theta5Slider.Value = app.savedAngles3(5);
            app.Theta6Slider.Value = app.savedAngles3(6);


            app.J1ValueEditField.Value = app.savedAngles3(1);
            app.J2ValueEditField.Value = app.savedAngles3(2)+90;
            app.J3ValueEditField.Value = app.savedAngles3(3);
            app.J4ValueEditField.Value = app.savedAngles3(4);
            app.J5ValueEditField.Value = app.savedAngles3(5);
            app.J6ValueEditField.Value = app.savedAngles3(6);
            app.updateArm()
        end

        % Value changing function: Theta5Slider
        function Theta5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(5) = changingValue;
            app.J5ValueEditField.Value = changingValue;
            app.updateArm()
        end

        % Value changing function: Theta4Slider
        function Theta4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(4) = changingValue;
            app.J4ValueEditField.Value = changingValue;
            app.updateArm()
            
        end

        % Value changing function: Theta6Slider
        function Theta6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(6) = changingValue;
            app.J6ValueEditField.Value = changingValue;
            app.updateArm()
        end

        % Callback function: not associated with a component
        function J4ValueEditFieldValueChanged(app, event)
            value = app.J4ValueEditField.Value;
            
        end

        % Callback function: not associated with a component
        function J5ValueEditFieldValueChanged(app, event)
            value = app.J5ValueEditField.Value;
            
        end

        % Callback function: not associated with a component
        function J6ValueEditFieldValueChanged(app, event)
            value = app.J6ValueEditField.Value;
            
        end

        % Value changed function: J1ValueEditField
        function J1ValueEditFieldValueChanged(app, event)
            value = app.J1ValueEditField.Value;
            app.Joint1Slider.Value = value;
        end

        % Value changed function: J2ValueEditField
        function J2ValueEditFieldValueChanged(app, event)
            value = app.J2ValueEditField.Value;
            app.Theta2Slider.Value = value;
        end

        % Value changed function: J3ValueEditField
        function J3ValueEditFieldValueChanged(app, event)
            value = app.J3ValueEditField.Value;
            app.Theta3Slider.Value = value;
        end

        % Value changed function: J4ValueEditField
        function J4ValueEditFieldValueChanged2(app, event)
            value = app.J4ValueEditField.Value;
            app.Theta4Slider.Value = value;
        end

        % Value changed function: J5ValueEditField
        function J5ValueEditFieldValueChanged2(app, event)
            value = app.J5ValueEditField.Value;
            app.Theta5Slider.Value = value;
        end

        % Value changed function: J6ValueEditField
        function J6ValueEditFieldValueChanged2(app, event)
            value = app.J6ValueEditField.Value;
            app.Theta6Slider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.SliderPanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.SliderPanel.Title = 'Sliders';
            app.Joint1Slider = uislider(app.SliderPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Theta2SliderLabel = uilabel(app.SliderPanel);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.SliderPanel);
            app.Theta2Slider.Limits = [-10 40];
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Theta3SliderLabel = uilabel(app.SliderPanel);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.SliderPanel);
            app.Theta3Slider.Limits = [-10 50];
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.Theta5SliderLabel = uilabel(app.SliderPanel);
            app.Theta5SliderLabel.Text = 'Theta 5';
            app.Theta5Slider = uislider(app.SliderPanel);
            app.Theta5Slider.Limits = [-90 90];
            app.Theta5Slider.ValueChangingFcn = createCallbackFcn(app, @Theta5SliderValueChanging, true);
            app.Theta4SliderLabel = uilabel(app.SliderPanel);
            app.Theta4SliderLabel.Text = 'Theta 4';
            app.Theta4Slider = uislider(app.SliderPanel);
            app.Theta4Slider.Limits = [-90 90];
            app.Theta4Slider.ValueChangingFcn = createCallbackFcn(app, @Theta4SliderValueChanging, true);
            app.Theta6SliderLabel = uilabel(app.SliderPanel);
            app.Theta6SliderLabel.Text = 'Theta 6';
            app.Theta6Slider = uislider(app.SliderPanel);
            app.Theta6Slider.Limits = [-180 180];
            app.Theta6Slider.ValueChangingFcn = createCallbackFcn(app, @Theta6SliderValueChanging, true);
            app.Theta1Label = uilabel(app.SliderPanel);
            app.Theta1Label.Text = 'Theta 1';
            app.ValuePanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.ValuePanel.Title = 'Numeric Entry';
            app.J1ValueEditFieldLabel = uilabel(app.ValuePanel);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.J1ValueEditField.Limits = [-90 90];
            app.J1ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J1ValueEditFieldValueChanged, true);
            app.J2ValueEditField_2Label = uilabel(app.ValuePanel);
            app.J2ValueEditField_2Label.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.J2ValueEditField.Limits = [-10 40];
            app.J2ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J2ValueEditFieldValueChanged, true);
            app.J3ValueEditFieldLabel = uilabel(app.ValuePanel);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.J3ValueEditField.Limits = [-10 50];
            app.J3ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J3ValueEditFieldValueChanged, true);
            app.GOButton = uibutton(app.ValuePanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.ValuePanel, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.J5ValueEditFieldLabel = uilabel(app.ValuePanel);
            app.J5ValueEditFieldLabel.Text = 'J5 Value';
            app.J5ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.J5ValueEditField.Limits = [-90 90];
            app.J5ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J5ValueEditFieldValueChanged2, true);
            app.J4ValueEditFieldLabel = uilabel(app.ValuePanel);
            app.J4ValueEditFieldLabel.Text = 'J4 Value';
            app.J4ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.J4ValueEditField.Limits = [-90 90];
            app.J4ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J4ValueEditFieldValueChanged2, true);
            app.J6ValueEditFieldLabel = uilabel(app.ValuePanel);
            app.J6ValueEditFieldLabel.Text = 'J6 Value';
            app.J6ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.J6ValueEditField.Limits = [-180 180];
            app.J6ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J6ValueEditFieldValueChanged2, true);
            app.ValuePanel_2 = uipanel(app.AdeeptArmGUIUIFigure);
            app.ValuePanel_2.Title = 'Save Position';
            app.Label = uilabel(app.ValuePanel_2);
            app.GOButton_2 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOButton_2Pushed, true);
            app.GOButton_2.Text = 'GO';
            app.SAVEButton = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.Label_2 = uilabel(app.ValuePanel_2);
            app.GOButton_3 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_3.ButtonPushedFcn = createCallbackFcn(app, @GOButton_3Pushed, true);
            app.GOButton_3.Text = 'GO';
            app.SAVEButton_2 = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_2Pushed, true);
            app.SAVEButton_2.Text = 'SAVE';
            app.Label_3 = uilabel(app.ValuePanel_2);
            app.GOButton_4 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_4.ButtonPushedFcn = createCallbackFcn(app, @GOButton_4Pushed, true);
            app.GOButton_4.Text = 'GO';
            app.SAVEButton_3 = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton_3.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_3Pushed, true);
            app.SAVEButton_3.Text = 'SAVE';
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABBArmGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end